classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        edgecomputeButton  matlab.ui.control.Button
        ComputeButton      matlab.ui.control.Button
        LoadButton         matlab.ui.control.Button
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LoadedImage % Property to store the loaded image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            file = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            img = imread('logo_csse.png');
            app.LoadedImage = img; % Store the loaded image
            imshow(img, 'Parent', app.UIAxes);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            grayImg = rgb2gray(app.LoadedImage);
            binaryImg = imbinarize(grayImg);
            imshow(binaryImg, 'Parent', app.UIAxes2);
        end

        % Button pushed function: edgecomputeButton
        function edgecomputeButtonPushed(app, event)
            grayImg = rgb2gray(app.LoadedImage);
            edges = edge(grayImg,'canny');
            imshow(edges, 'Parent', app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.edgecomputeButton = uibutton(app.UIFigure, 'push');
            app.edgecomputeButton.ButtonPushedFcn = createCallbackFcn(app, @edgecomputeButtonPushed, true);
            app.edgecomputeButton.Text = 'edge compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end